classdef fin_optimizer_noalpha < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        FinDragOptimizerwithConstrainedStabilityMarginLabel  matlab.ui.control.Label
        MachEditFieldLabel             matlab.ui.control.Label
        MachEditField                  matlab.ui.control.NumericEditField
        CharacteristicReynoldsLabel    matlab.ui.control.Label
        ReynoldsField                  matlab.ui.control.NumericEditField
        OUTPUTSLabel                   matlab.ui.control.Label
        INPUTSLabel                    matlab.ui.control.Label
        FinDragCoefficientEditFieldLabel  matlab.ui.control.Label
        FinDragCoefficientField        matlab.ui.control.NumericEditField
        RootChordmEditFieldLabel       matlab.ui.control.Label
        RootChordField                 matlab.ui.control.NumericEditField
        TipChordmEditFieldLabel        matlab.ui.control.Label
        TipChordField                  matlab.ui.control.NumericEditField
        SweepDistancemEditFieldLabel   matlab.ui.control.Label
        SweepDistanceField             matlab.ui.control.NumericEditField
        SemiSpanmEditFieldLabel        matlab.ui.control.Label
        SemiSpanField                  matlab.ui.control.NumericEditField
        StabilityMarginEditFieldLabel  matlab.ui.control.Label
        StabilityMarginEditField       matlab.ui.control.NumericEditField
        FinExposedAreaEditFieldLabel   matlab.ui.control.Label
        FinExposedAreaEditField        matlab.ui.control.NumericEditField
        DragMethodSwitchLabel          matlab.ui.control.Label
        DragMethodSwitch               matlab.ui.control.Switch
    end


    methods (Access = private)
        
        
        function result = test(app)
            
            
            if app.DragMethodSwitch.Value == 'OR'
                drag = @DRAG_OR
            elseif app.DragMethodSwitch.Value == 'AC'
                drag = @DRAG_AC
            end
            
            X0 = [0.1;0.1;0.1;0.1]; %Initial Conditions for [a,b,m,s]
            A = [-0.75,1,0,0;
                0,-0.9,1,0;
                -1,0,0,1];
            B = [0;0;0];
            Aeq = [];
            Beq = [];
            LB = [0;0;0;0];
            UB = [0.5;Inf;Inf;Inf];
            
            X = fmincon(@(X) drag(app,X),X0,A,B,Aeq,Beq,LB,UB,@(X) NONLCON(app,X))
            % FUN = Fin Drag Equation (Subsonic)
            
            % M = CP_FINAL(app,X);
            app.FinDragCoefficientField.Value = drag(app,X)
            
            app.RootChordField.Value = X(1);
            app.TipChordField.Value = X(2);
            app.SweepDistanceField.Value = X(3);
            app.SemiSpanField.Value = X(4);
            
            
            graph_vec = [0,0;X(3),X(4);(X(3)+X(2)),X(4);X(1),0];
            
            plot(app.UIAxes,graph_vec(1:4),graph_vec(5:8));
            xlim(app.UIAxes,[0,0.15]);
            ylim(app.UIAxes,[0,0.15]);
            
            result = 1;
        end
        
        function F = DRAG_OR(app,X)
            load('velocityprofile.mat','yeet');
            
            n = length(yeet);
            Cd_out = NaN(1,n);
            Cd_incomp_out = NaN(1,n);
            for i = 1:n
                [Cd_out(i),Cd_incomp_out(i)] = drag_AC(app,X,yeet(i));
            end
            
            F = num_int(app,Cd_out);
        end
        
        function area = num_int(app,vec)
            h = 1; %dx size
            a = 0;
            n = length(vec);
            for i = 1:2:n-2
                da = (1/3)*h*(vec(i)+4*vec(i+1)+vec(i+2));
                a = a + da;
            end
            area = a;
        end
        
        function [Cd,Cd_incomp] = drag_OR_calc(app,X,M)
            % Interference Drag and Fin-Tip Vortices are ignored as they are considered
            % "relatively small"
            % Also assumed a fully turbulent boundary layer
            % Constants (all dimensions in meters):
            L_n = 0.548;    % Nose Length
            F_r = 0.406;    % Fin Root Chord
            F_t = 0.127;    % Fin Tip Chord
            F_s = 0.152;    % Fin Semi-Span
            S = 0.100;      % Sweep Distance
            L_r = 5.844;    % Rocket Length
            L_z = 0.0762;   % Nozzle Length
            N = 3;          % # of fins
            Cn_n = 0.5;     % Nose Cone Coefficient
            t = 0.00635;    % Max Fin Root Thickness
            X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
            L_red = 0.05995;% Length Reduction @ back
            D_noz = 0.08204;% Nozzle Diameter
            D_nos = 0.140;  % Nose Base Diameter
            D_end = 0.1077; % End Diameter
            F_w = 0.00381;  % Fin Thickness
            F_fl = 0.21895; % Fin Front Length
            Cg = 3.372354308; %Centre of Gravity
            
            % Geometric Dimensions
            Afe = (1/2)*(X(1)+X(2))*X(4);
            Afp = (1/2)*D_nos*X(1) + Afe; % Fin Planform Area (+ Body)
            mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4))); % Midchord
            tr = X(2)/X(1);
            MAC = X(1)*(2/3)*((1+tr+tr^2)/(1+tr));
            %Abody =
            Aref = N*(F_w*X(4));
            
            %Reynolds Number
            rho = 1.225 ;%sea level, 15degC (ISA) [kg/m^3]
            
            Ma = 340.3 ;%sea level, 15degC [m/s]
            V = M*Ma;
            mu = 1.802*(10^-5); % 15degC [kg/m*s]
            nu = 1.470*(10^-5); %15degC
            Rc = 51*((5*10^-6)/L_r)^-1.039;
            Re = (V*mid)/mu; % REYNOLDS NUMBER (MAC = characteristic length, no density)
            %Skin Friction Coefficients for Varying Regimes of Flow
            if Re < 10^4
                Cf = 1.48*(10^-2);
            elseif Re < Rc
                Cf = 1/((1.5*log(Re)-5.6)^2);
            elseif Re > Rc
                Cf = 0.032*((5*10^-6)/L_r)^0.2;
            end
            %Compressibility Corrections
            if V < Ma
                Cfc = Cf*(1-0.1*M^2);
            elseif V > Ma
                turb = Cf/((1+0.15*M^2)^0.58);
                rough = Cf/(1+0.18*M^2);
                if Re < Rc
                    Cfc = turb;
                elseif Re > Rc
                    if rough < turb
                        Cfc = turb;
                    elseif rough > turb
                        Cfc = rough;
                    end
                end
            end
            
            %Skin Friction Drag Coefficient
            Cdf = (Cfc*((1+(2*F_w)/MAC)*6*Afe))/Aref;
            
            % Fin Pressure Drag Coefficient
            % firstly, the leading edge pressure drag (assuming rounded leading edge):
            if M < 0.9
                Cd_le = ((1-M^2)^-0.417) - 1;
            elseif M < 1
                Cd_le = 1- 1.785*(M-0.9);
            elseif M >= 1
                Cd_le = 1.214 - (0.502/M^2) + (0.1095/M^4);
            end
            % now correcting it for sweep:
            Cd_le_sweep = Cd_le*(cos(atan(X(3)/X(4))^2));
            % Cd_te (or trailing edge) is 0 due to tapered geometry. The resulting Cdp
            % (fin pressure drag) is thus a scaled value of Cd_le_sweep.
            
            Cdp = (Cd_le_sweep*6*Afe)/Aref;
            
            Cd_incomp = Cdp+Cdf;
            
            % Compressibility Correction (extending to compressible flow regime)
            % Prandtl-Glauert compressibility correction
            if M < 0.8
                Cd = Cd_incomp/(sqrt(1-M^2));
            elseif M > 1.1
                Cd = Cd_incomp/(sqrt(M^2-1));
            elseif (0.8 <= M) && (M <= 1.1)
                Cd = Cd_incomp/(sqrt(1-0.8^2)); %Ketchledge [1993] correction to avoid approaching infinity as M -> 1
            end
            
            
            % NOTE: Transonic aerodynamic properties are accounted for "by using some
            % suitable interpolation function" [OR,pg.18] Not sure where to find this. OR
            % also does not take into account Hypersonic (Ma > 5)
            
        end
        
        function [Cd,Cd_alpha] = drag_AC(app,X,M)
            % Constants (all dimensions in meters):
            L_n = 0.548;    % Nose Length
            F_r = 0.406;    % Fin Root Chord
            F_t = 0.127;    % Fin Tip Chord
            F_s = 0.152;    % Fin Semi-Span
            S = 0.100;      % Sweep Distance
            L_r = 5.844;    % Rocket Length
            L_z = 0.0762;   % Nozzle Length
            N = 3;          % # of fins
            Cn_n = 0.5;     % Nose Cone Coefficient
            t = 0.00635;    % Max Fin Root Thickness
            X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
            L_red = 0.05995;% Length Reduction @ back
            D_noz = 0.08204;% Nozzle Diameter
            D_nos = 0.140;  % Nose Base Diameter
            D_end = 0.1077; % End Diameter
            F_w = 0.00381;  % Fin Thickness
            F_fl = 0.21895; % Fin Front Length
            Cg = 3.372354308; %Centre of Gravity
            
            % Geometric Dimensions
            Afe = (1/2)*(X(1)+X(2))*X(4); % Fin Exposed Area
            app.FinExposedAreaEditField.Value = Afe;
            Afp = (1/2)*D_nos*X(1) + Afe; % Fin Planform Area
            mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4))); % Midchord
            
            %Reynolds Number
            rho = 1.225; %sea level, 15degC (ISA) [kg/m^3]
            Ma = 340.3; %sea level, 15degC [m/s]
            V = M*Ma;
            mu = 1.802*(10^-5); % 15degC [kg/m*s]
            Re = (rho*V*mid)/mu; % 1,072,936.44 > Re_c = 5x10^5
            Re_c = 5*(10^5);
            app.ReynoldsField.Value = Re;
            
            B = Re_c*((0.074/(Re^(1/5)))-(1.328/sqrt(Re)));
            if Re > Re_c
                Cff = (0.074/((Re)^(1/5)))-(B/Re); % Viscous Friction Force Coefficient
            elseif Re <= Re_c
                Cff = 1.328/(sqrt(Re));
            end
            % Fin Drag (scales with 1st deg wrt Cff)
            Cdf = 2*Cff*(1+2*(F_w/mid))*(4*N*Afp/(pi*(D_nos^2)));
            
            % Fin-Body Interference Drag (scales with 1st deg wrt Cff)
            Cdi = 2*Cff*(1+2*(F_w/mid))*(4*N*(Afp-Afe)/(pi*(D_nos^2)));
            
            % Angle of Attack Correction Drag Coefficient
            alpha = 5; % Angle of Attack [deg]
            delta = 0.825; % Constant interpolated from Fig 4. [AerodynamicCoefficients.pdf]
            nu = 0.6125; % Constant interpolated from Fig 4. [AerodynamicCoefficients.pdf]
            L_ts = 2*X(4) + D_nos; % Total Span
            Rs = L_ts/D_nos; % Fin Section Ratio
            
            kfb = 0.8065*Rs^2 + 1.1553*Rs; % Fin-body intereference coefficient
            kbf = 0.1935*Rs^2 + 0.8174*Rs + 1; % Body-fin interference coefficient
            
            %CORRECTION DRAG FACTOR FOR ANGLE OF ATTACK
            Cdf_alpha = (alpha^2)*(((1.2*(Afp*4))/(pi*D_nos^2))+3.12*(kfb+kbf-1)*((Afe*4)/(pi*D_nos^2)));
            
            % TOTAL DRAG COEFFICIENT W/O COMPRESSIBLE FLOW CORRECTION
            C_uncompress = Cdf+Cdi;
            C_uncompress_alpha = Cdf + Cdi + Cdf_alpha;
            % Compressible Flow Correction
            if M < 0.8
                C_compress = C_uncompress/sqrt(1-(M)^2);
                C_compress_alpha = C_uncompress_alpha/sqrt(1-(M)^2);
            elseif M > 1.1
                
                C_compress = C_uncompress/sqrt(((M)^2)-1);
                C_compress_alpha = C_uncompress_alpha/sqrt(((M)^2)-1);
            else
                C_compress = C_uncompress/sqrt(1-0.8^2);
                C_compress_alpha = C_uncompress_alpha/sqrt(1-0.8^2);
            end
            
            Cd = C_compress;
            Cd_alpha = C_compress_alpha;
            
        end
        
        
        function [margin_final] = CP_FINAL(app,X)
            % Constants (all dimensions in meters):
            L_n = 0.548;    % Nose Length
            F_r = 0.406;    % Fin Root Chord
            F_t = 0.127;    % Fin Tip Chord
            F_s = 0.152;    % Fin Semi-Span
            S = 0.100;      % Sweep Distance
            L_r = 5.844;    % Rocket Length
            L_z = 0.0762;   % Nozzle Length
            N = 3;          % # of fins
            Cn_n = 0.5;     % Nose Cone Coefficient
            t = 0.00635;    % Max Fin Root Thickness
            X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
            L_red = 0.05995;% Length Reduction @ back
            D_noz = 0.08204;% Nozzle Diameter
            D_nos = 0.140;  % Nose Base Diameter
            D_end = 0.1077; % End Diameter
            F_w = 0.00381;  % Fin Width (Thickness)
            F_fl = 0.21895; % Fin Front Length
            Cg = 3.372354308; %Centre of Gravity
            
            % X (distance from nose tip to component's Cp):
            % NOTE: Normal Body and Shoulder Forces are neglected
            X_n = L_n/2;
            X_b = L_r - L_red + (L_red/3)*(1+1/(1+D_nos/D_end));
            
            % Component Coefficient of Normal Force (Cn)
            Cn_n = 2;
            Cn_b = 2*(((D_end/D_nos)^2)-1);
            
            %Optimization Variables = X(1) = a, X(2) = b, X(3) = m, X(4) = s
            
            % Using default values for all other variables,
            % % we can cast final Cp position as cp(a,b,m,s):
            mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4)));
            l =  (L_r-X(1))-L_red;
            int = 1+ (D_nos/2)/(X(4)+(D_nos/2));
            c =  int*(4*N*(X(4)/D_nos)^2)/(1+sqrt(1+(2*mid/(X(1)+X(2)))^2));
            x =  l+ (X(3)*(X(1)+2*X(2)))/(3*(X(1)+X(2))) + (1/6)*(X(1)+X(2)-(X(1)*X(2))/(X(1)+X(2)));
            cp =  ((Cn_n*X_n)+(Cn_b*X_b)+(c*x))/(Cn_n + Cn_b + c);
            
            margin_final = (cp-Cg)/D_nos;
            
        end
        
        function [C,Ceq] = NONLCON(app,X)
            C = [];
            % Constants (all dimensions in meters):
            L_n = 0.548;    % Nose Length
            F_r = 0.406;    % Fin Root Chord
            F_t = 0.127;    % Fin Tip Chord
            F_s = 0.152;  % Fin Semi-Span
            S = 0.100;      % Sweep Distance
            L_r = 5.844;  % Rocket Length
            L_z = 0.0762;   % Nozzle Length
            N = 3;          % # of fins
            Cn_n = 0.5;     % Nose Cone Coefficient
            t = 0.00635;    % Max Fin Root Thickness
            X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
            L_red = 0.05995;% Length Reduction @ back
            D_noz = 0.08204;% Nozzle Diameter
            D_nos = 0.140;  % Nose Base Diameter
            D_end = 0.1077; % End Diameter
            F_w = 0.00381;    % Fin Width (Thickness)
            F_fl = 0.21895; % Fin Front Length
            Cg = 3.372354308; %Centre of Gravity
            
            % X (distance from nose tip to component's Cp):
            % NOTE: Normal Body and Shoulder Forces are neglected
            X_n = L_n/2;
            X_b = L_r - L_red + (L_red/3)*(1+1/(1+D_nos/D_end));
            
            % Component Coefficient of Normal Force (Cn)
            Cn_n = 2;
            Cn_b = 2*(((D_end/D_nos)^2)-1);
            
            %Optimization Variables = X(1) = a, X(2) = b, X(3) = m, X(4) = s
            
            % Using default values for all other variables,
            % % we can cast final Cp position as cp(a,b,m,s):
            mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4)));
            l =  (L_r-X(1))-L_red;
            int = 1+ (D_nos/2)/(X(4)+(D_nos/2));
            c =  int*(4*N*(X(4)/D_nos)^2)/(1+sqrt(1+(2*mid/(X(1)+X(2)))^2));
            x =  l+ (X(3)*(X(1)+2*X(2)))/(3*(X(1)+X(2))) + (1/6)*(X(1)+X(2)-(X(1)*X(2))/(X(1)+X(2)));
            cp =  ((Cn_n*X_n)+(Cn_b*X_b)+(c*x))/(Cn_n + Cn_b + c);
            
            margin = (cp-Cg)/D_nos;
            Ceq = [margin-app.StabilityMarginEditField.Value];
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: MachEditField
        function MachEditFieldValueChanged(app, event)
            if app.MachEditField.Value ~= 0 && app.StabilityMarginEditField.Value ~= 0
                result_fun = test(app);
            end
        end

        % Value changed function: StabilityMarginEditField
        function StabilityMarginEditFieldValueChanged(app, event)
            if app.MachEditField.Value ~= 0 && app.StabilityMarginEditField.Value ~= 0
                result_fun = test(app);
            end
        end

        % Value changed function: DragMethodSwitch
        function DragMethodSwitchValueChanged(app, event)
            if app.MachEditField.Value ~= 0 && app.StabilityMarginEditField.Value ~= 0
                result_fun = test(app);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.FinDragOptimizerwithConstrainedStabilityMarginLabel = uilabel(app.UIFigure);
            app.FinDragOptimizerwithConstrainedStabilityMarginLabel.Text = 'Fin Drag Optimizer with Constrained Stability Margin';
            app.MachEditFieldLabel = uilabel(app.UIFigure);
            app.MachEditFieldLabel.Text = 'Mach #:';
            app.MachEditField = uieditfield(app.UIFigure, 'numeric');
            app.MachEditField.Limits = [0 Inf];
            app.MachEditField.ValueChangedFcn = createCallbackFcn(app, @MachEditFieldValueChanged, true);
            app.CharacteristicReynoldsLabel = uilabel(app.UIFigure);
            app.CharacteristicReynoldsLabel.Text = 'Characteristic Reynold''s #:';
            app.ReynoldsField = uieditfield(app.UIFigure, 'numeric');
            app.ReynoldsField.Editable = 'off';
            app.OUTPUTSLabel = uilabel(app.UIFigure);
            app.OUTPUTSLabel.Text = 'OUTPUTS';
            app.INPUTSLabel = uilabel(app.UIFigure);
            app.INPUTSLabel.Text = 'INPUTS';
            app.FinDragCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.FinDragCoefficientEditFieldLabel.Text = 'Fin Drag Coefficient:';
            app.FinDragCoefficientField = uieditfield(app.UIFigure, 'numeric');
            app.FinDragCoefficientField.Editable = 'off';
            app.RootChordmEditFieldLabel = uilabel(app.UIFigure);
            app.RootChordmEditFieldLabel.Text = 'Root Chord [m]:';
            app.RootChordField = uieditfield(app.UIFigure, 'numeric');
            app.RootChordField.Editable = 'off';
            app.TipChordmEditFieldLabel = uilabel(app.UIFigure);
            app.TipChordmEditFieldLabel.Text = 'Tip Chord [m]:';
            app.TipChordField = uieditfield(app.UIFigure, 'numeric');
            app.TipChordField.Editable = 'off';
            app.SweepDistancemEditFieldLabel = uilabel(app.UIFigure);
            app.SweepDistancemEditFieldLabel.Text = 'Sweep Distance [m]:';
            app.SweepDistanceField = uieditfield(app.UIFigure, 'numeric');
            app.SweepDistanceField.Editable = 'off';
            app.SemiSpanmEditFieldLabel = uilabel(app.UIFigure);
            app.SemiSpanmEditFieldLabel.Text = 'Semi-Span [m]:';
            app.SemiSpanField = uieditfield(app.UIFigure, 'numeric');
            app.SemiSpanField.Editable = 'off';
            app.StabilityMarginEditFieldLabel = uilabel(app.UIFigure);
            app.StabilityMarginEditFieldLabel.Text = 'Stability Margin:';
            app.StabilityMarginEditField = uieditfield(app.UIFigure, 'numeric');
            app.StabilityMarginEditField.Limits = [0 Inf];
            app.StabilityMarginEditField.ValueChangedFcn = createCallbackFcn(app, @StabilityMarginEditFieldValueChanged, true);
            app.FinExposedAreaEditFieldLabel = uilabel(app.UIFigure);
            app.FinExposedAreaEditFieldLabel.Text = 'Fin Exposed Area:';
            app.FinExposedAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinExposedAreaEditField.Editable = 'off';
            app.DragMethodSwitchLabel = uilabel(app.UIFigure);
            app.DragMethodSwitchLabel.Text = 'Drag Method';
            app.DragMethodSwitch = uiswitch(app.UIFigure, 'slider');
            app.DragMethodSwitch.Items = {'OR', 'AC'};
            app.DragMethodSwitch.ValueChangedFcn = createCallbackFcn(app, @DragMethodSwitchValueChanged, true);
            app.DragMethodSwitch.Value = 'OR';
        end
    end

    methods (Access = public)

        % Construct app
        function app = fin_optimizer_noalpha

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end